classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes_settings             matlab.ui.control.UIAxes
        UploadReferenceImageButton  matlab.ui.control.Button
        UploadDeformedImagesButton  matlab.ui.control.Button
        PerformAnalysisButton       matlab.ui.control.Button
        SaveDataButton              matlab.ui.control.Button
        DefineROIButton             matlab.ui.control.Button
        ExclusionsButton            matlab.ui.control.Button
        StepSizeSpinnerLabel        matlab.ui.control.Label
        StepSizeSpinner             matlab.ui.control.Spinner
        SubsetSizeSpinnerLabel      matlab.ui.control.Label
        SubsetSizeSpinner           matlab.ui.control.Spinner
        ShapeFunctionDropDownLabel  matlab.ui.control.Label
        ShapeFunctionDropDown       matlab.ui.control.DropDown
        VSGShapeDropDownLabel       matlab.ui.control.Label
        VSGShapeDropDown            matlab.ui.control.DropDown
        TabGroup                    matlab.ui.container.TabGroup
        SettingsTab                 matlab.ui.container.Tab
        DataTab                     matlab.ui.container.Tab
        UIAxes_data                 matlab.ui.control.UIAxes
        QOIDropDownLabel            matlab.ui.control.Label
        QOIDropDown                 matlab.ui.control.DropDown
        Tree                        matlab.ui.container.Tree
        DeformedImageNode           matlab.ui.container.TreeNode
        Node2                       matlab.ui.container.TreeNode
        Node3                       matlab.ui.container.TreeNode
        Node4                       matlab.ui.container.TreeNode
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadReferenceImageButton
        function UploadReferenceImageButtonPushed(app, event)
            global ref ROI_coords;
            ref = upload_ref_image;
            imagesc(app.UIAxes_settings,ref)
            axis(app.UIAxes_settings,'equal')
            colormap(app.UIAxes_settings, 'gray')
            ROI_coords.px_exclude.i_px = []; % initialize any exclusions to null
            ROI_coords.px_exclude.j_px = []; % initialize any exclusions to null
            ROI_coords.px_include.i_px = []; % initialize any inclusions to null
            ROI_coords.px_include.j_px = []; % initialize any inclusions to null

        end

        % Button pushed function: UploadDeformedImagesButton
        function UploadDeformedImagesButtonPushed(app, event)
            global def;
            def = upload_def_images;            
        end

        % Button pushed function: DefineROIButton
        function DefineROIButtonPushed(app, event)
            global ref ROI_coords;
            ROI_coords.px_include = ROI(ref);
        end

        % Button pushed function: ExclusionsButton
        function ExclusionsButtonPushed(app, event)
            global ref ROI_coords;
            ROI_coords.px_exclude = ROI_exclude(ref);            
        end

        % Value changed function: StepSizeSpinner
        function StepSizeSpinnerValueChanged(app, event)
            value = app.StepSizeSpinner.Value;
            global subset_info;
            subset_info.step_size = value;
        end

        % Value changed function: SubsetSizeSpinner
        function SubsetSizeSpinnerValueChanged(app, event)
            value = app.SubsetSizeSpinner.Value;
            global subset_info;
            subset_info.size = value;

        end

        % Value changed function: ShapeFunctionDropDown
        function ShapeFunctionDropDownValueChanged(app, event)
            value = app.ShapeFunctionDropDown.Value;
            global subset_info;
            subset_info.shape_function = value;
            
        end

        % Button pushed function: PerformAnalysisButton
        function PerformAnalysisButtonPushed(app, event)
            global subset_info ref def ROI_coords DIC_output;
            DIC_output = main(subset_info,ref,def,ROI_coords);
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            global DIC_output;
            userinput = input('Enter file name: ' , 's');
            save(strcat(userinput,'.mat'),'-struct','DIC_output')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.UIAxes_settings = uiaxes(app.SettingsTab);
            title(app.UIAxes_settings, 'Image Data')
            xlabel(app.UIAxes_settings, 'X')
            ylabel(app.UIAxes_settings, 'Y')
            app.UploadReferenceImageButton = uibutton(app.SettingsTab, 'push');
            app.UploadReferenceImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadReferenceImageButtonPushed, true);
            app.UploadReferenceImageButton.Text = 'Upload Reference Image';
            app.UploadDeformedImagesButton = uibutton(app.SettingsTab, 'push');
            app.UploadDeformedImagesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDeformedImagesButtonPushed, true);
            app.UploadDeformedImagesButton.Text = 'Upload Deformed Images';
            app.PerformAnalysisButton = uibutton(app.SettingsTab, 'push');
            app.PerformAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PerformAnalysisButtonPushed, true);
            app.PerformAnalysisButton.Text = 'Perform Analysis';
            app.SaveDataButton = uibutton(app.SettingsTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.DefineROIButton = uibutton(app.SettingsTab, 'push');
            app.DefineROIButton.ButtonPushedFcn = createCallbackFcn(app, @DefineROIButtonPushed, true);
            app.DefineROIButton.Text = 'Define ROI';
            app.ExclusionsButton = uibutton(app.SettingsTab, 'push');
            app.ExclusionsButton.ButtonPushedFcn = createCallbackFcn(app, @ExclusionsButtonPushed, true);
            app.ExclusionsButton.Text = 'Exclusions';
            app.StepSizeSpinnerLabel = uilabel(app.SettingsTab);
            app.StepSizeSpinnerLabel.Text = 'Step Size';
            app.StepSizeSpinner = uispinner(app.SettingsTab);
            app.StepSizeSpinner.Limits = [1 61];
            app.StepSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @StepSizeSpinnerValueChanged, true);
            app.StepSizeSpinner.Value = 1;
            app.SubsetSizeSpinnerLabel = uilabel(app.SettingsTab);
            app.SubsetSizeSpinnerLabel.Text = 'Subset Size';
            app.SubsetSizeSpinner = uispinner(app.SettingsTab);
            app.SubsetSizeSpinner.Step = 2;
            app.SubsetSizeSpinner.Limits = [11 61];
            app.SubsetSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @SubsetSizeSpinnerValueChanged, true);
            app.SubsetSizeSpinner.Value = 11;
            app.ShapeFunctionDropDownLabel = uilabel(app.SettingsTab);
            app.ShapeFunctionDropDownLabel.Text = 'Shape Function';
            app.ShapeFunctionDropDown = uidropdown(app.SettingsTab);
            app.ShapeFunctionDropDown.Items = {'Affine', 'Quadratic', 'Option 3', 'Option 4'};
            app.ShapeFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @ShapeFunctionDropDownValueChanged, true);
            app.ShapeFunctionDropDown.Value = 'Affine';
            app.VSGShapeDropDownLabel = uilabel(app.SettingsTab);
            app.VSGShapeDropDownLabel.Text = 'VSG Shape';
            app.VSGShapeDropDown = uidropdown(app.SettingsTab);
            app.VSGShapeDropDown.Items = {'Circle', 'Square', 'Option 3', 'Option 4'};
            app.VSGShapeDropDown.Value = 'Circle';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UIAxes_data = uiaxes(app.DataTab);
            title(app.UIAxes_data, 'Image Data')
            xlabel(app.UIAxes_data, 'X')
            ylabel(app.UIAxes_data, 'Y')
            app.QOIDropDownLabel = uilabel(app.DataTab);
            app.QOIDropDownLabel.Text = 'QOI';
            app.QOIDropDown = uidropdown(app.DataTab);
            app.QOIDropDown.Items = {'u', 'v', 'exx', 'eyy'};
            app.QOIDropDown.Value = 'u';
            app.Tree = uitree(app.DataTab);
            app.DeformedImageNode = uitreenode(app.Tree);
            app.DeformedImageNode.Text = 'Deformed Image';
            app.Node2 = uitreenode(app.DeformedImageNode);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.DeformedImageNode);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.DeformedImageNode);
            app.Node4.Text = 'Node4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end